classdef concave_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Kuleshov_diplomaUIFigure   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        BuildButton                matlab.ui.control.Button
        DisplayingstepSliderLabel  matlab.ui.control.Label
        StepSlider                 matlab.ui.control.Slider
        EnternumberofstepsEditFieldLabel  matlab.ui.control.Label
        N_STEPSEditField           matlab.ui.control.NumericEditField
        ConcavehullbuilderLabel    matlab.ui.control.Label
        SetBETAEditFieldLabel      matlab.ui.control.Label
        BETAEditField              matlab.ui.control.NumericEditField
        AutoSwitchLabel            matlab.ui.control.Label
        AutoSwitch                 matlab.ui.control.Switch
    end

    properties (Access = private)
        N_STEPS
        BETA
        X_VEC % x_vec
        U_MAT % u_mat
        IND_SPEC % ind_spec
        IND_ADD 
        SW_VAL
        N_PREV_STEPS
        N_POINTS
    end

    methods (Access = private)
    
        function build_shell(app)
            [x_vec, u_mat, ind_spec, ind_add] = conc_hull_bld(app.N_STEPS, app.BETA, app);
            app.X_VEC = x_vec;
            app.U_MAT = u_mat;
            app.IND_SPEC = ind_spec;
            app.IND_ADD = ind_add;
            app.UIAxes.YLabel.String = ['f_{',num2str(app.StepSlider.Value),'}(x)'];
        end
        
        function build_button(app)
           app.StepSlider.Limits = [0 app.N_STEPS];
           app.StepSlider.MajorTicks = 0:app.N_STEPS;
           app.StepSlider.MajorTickLabels = strsplit(num2str(0:app.N_STEPS));
           app.StepSlider.Value = app.N_STEPS; 
           build_shell(app);
           graph_raw(app.X_VEC, app.U_MAT{end}, app.BETA, app.N_STEPS, app.IND_SPEC, app.IND_ADD, app);
        end
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          app.N_STEPS = app.N_STEPSEditField.Value;
          app.BETA = app.BETAEditField.Value;
          build_shell(app);   
          graph_raw(app.X_VEC, app.U_MAT{1}, app.BETA, 0, app.IND_SPEC, app.IND_ADD, app);
        end

        % Button pushed function: BuildButton
        function BuildButtonPushed(app, event)
           build_button(app);
        end

        % Value changed function: N_STEPSEditField
        function N_STEPSEditFieldValueChanged(app, event)
            app.N_STEPS = app.N_STEPSEditField.Value;
            if strcmp(app.SW_VAL, 'On')
                build_button(app);
            end
        end

        % Value changed function: StepSlider
        function StepSliderValueChanged(app, event)
            cur_step = round(app.StepSlider.Value);
            app.StepSlider.Value = cur_step; 
            graph_raw(app.X_VEC, app.U_MAT{cur_step+1}, app.BETA, cur_step, app.IND_SPEC, app.IND_ADD, app);
            app.UIAxes.YLabel.String = ['f_',num2str(app.StepSlider.Value),'(x)'];
        end

        % Value changed function: BETAEditField
        function BETAEditFieldValueChanged(app, event)
            app.BETA  = app.BETAEditField.Value; 
            if strcmp(app.SW_VAL, 'On')
                build_button(app);
            end
        end

        % Value changed function: AutoSwitch
        function AutoSwitchValueChanged(app, event)
            app.SW_VAL = app.AutoSwitch.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Kuleshov_diplomaUIFigure = uifigure;
            app.Kuleshov_diplomaUIFigure.Name = 'Kuleshov_diploma';
            app.UIAxes = uiaxes(app.Kuleshov_diplomaUIFigure);
            xlabel(app.UIAxes, 'X')
            app.BuildButton = uibutton(app.Kuleshov_diplomaUIFigure, 'push');
            app.BuildButton.ButtonPushedFcn = createCallbackFcn(app, @BuildButtonPushed, true);
            app.BuildButton.Text = 'Build';
            app.DisplayingstepSliderLabel = uilabel(app.Kuleshov_diplomaUIFigure);
            app.DisplayingstepSliderLabel.Text = {'Displaying'; 'step'};
            app.StepSlider = uislider(app.Kuleshov_diplomaUIFigure);
            app.StepSlider.Limits = [0 1];
            app.StepSlider.MajorTicks = [0 1];
            app.StepSlider.ValueChangedFcn = createCallbackFcn(app, @StepSliderValueChanged, true);
            app.StepSlider.MinorTicks = [];
            app.EnternumberofstepsEditFieldLabel = uilabel(app.Kuleshov_diplomaUIFigure);
            app.EnternumberofstepsEditFieldLabel.Text = {'Enter number'; ' of steps'};
            app.N_STEPSEditField = uieditfield(app.Kuleshov_diplomaUIFigure, 'numeric');
            app.N_STEPSEditField.Limits = [0 Inf];
            app.N_STEPSEditField.RoundFractionalValues = 'on';
            app.N_STEPSEditField.ValueChangedFcn = createCallbackFcn(app, @N_STEPSEditFieldValueChanged, true);
            app.N_STEPSEditField.Value = 1;
            app.ConcavehullbuilderLabel = uilabel(app.Kuleshov_diplomaUIFigure);
            app.ConcavehullbuilderLabel.Text = 'Concave hull builder';
            app.SetBETAEditFieldLabel = uilabel(app.Kuleshov_diplomaUIFigure);
            app.SetBETAEditFieldLabel.Text = 'Set BETA';
            app.BETAEditField = uieditfield(app.Kuleshov_diplomaUIFigure, 'numeric');
            app.BETAEditField.LowerLimitInclusive = 'off';
            app.BETAEditField.Limits = [1 Inf];
            app.BETAEditField.ValueChangedFcn = createCallbackFcn(app, @BETAEditFieldValueChanged, true);
            app.BETAEditField.Value = 2;
            app.AutoSwitchLabel = uilabel(app.Kuleshov_diplomaUIFigure);
            app.AutoSwitchLabel.Text = 'Auto';
            app.AutoSwitch = uiswitch(app.Kuleshov_diplomaUIFigure, 'slider');
            app.AutoSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoSwitchValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = concave_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Kuleshov_diplomaUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Kuleshov_diplomaUIFigure)
        end
    end
end